classdef TuftVizi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        VideoformatssupportedDropDownLabel  matlab.ui.control.Label
        VideoformatssupportedDropDown  matlab.ui.control.DropDown
        VideofilesDropDownLabel        matlab.ui.control.Label
        VideofilesDropDown             matlab.ui.control.DropDown
        ImageButton                    matlab.ui.control.Button
        VideoButton                    matlab.ui.control.Button
        ChoosedatatypeLabel            matlab.ui.control.Label
        UseAnalyticaltoolsButton       matlab.ui.control.Button
        UseMachineLearningToolsButton  matlab.ui.control.Button
        ChoosedatatypeLabel_2          matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        
    end
    
    properties (Access = public)
        ImageTuneStruct % Description
        FlowAngle
        OriginalVideo
        Mask
        CropFrame
        CroppedMask
        BrightnessKnob
        AlphaKnob
        SigmaKnob
        LowInSlider
        HighInSlider
        GammaKnob
        RadiusSlider
        SharpnessstrengthKnob
        ThresholdSlider
        SensitivitySpinner
        TuftcolorButtonGroup
        MinSpinner
        MaxSpinner % Description
        Image % Description
        OriginalImage
        Part
        TuneData
    end
    
    methods (Access = private)
        
        function video_format_choose(app)
            di=dir; count=2; videos(1)= string('choose...');
            for i=1:length(di)
                k=strfind(di(i).name,app.VideoformatssupportedDropDown.Value);
                if ~isempty(k)
                    videos(count)=string(di(i).name);
                    count=count+1;
                end
            end
            if exist('videos')
                if ~isempty(videos)
                    app.VideofilesDropDown.Items=videos;
                end
            end
            clear videos
        end
        
        
        function show_part_two(app)
            app.ImageButton.Visible='off';app.VideoButton.Visible='off';
            app.ChoosedatatypeLabel.Visible='off';
            app.ChoosedatatypeLabel_2.Visible = 'on';
            app.UseMachineLearningToolsButton.Visible = 'on';
            app.UseAnalyticaltoolsButton.Visible = 'on';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, part, ImageTuneStruct)
            try
                app.Part=part;
            end
            if ~exist('part')
                app.Part=1;
                s=VideoReader.getFileFormats;
                for i=1:length(s)
                    ms(i)=string(s(1,i).Extension);
                end
                app.VideoformatssupportedDropDown.Items=ms;
                app.VideofilesDropDown.Items = {'none'};
            elseif app.Part==2
                %                 global imagetune;
                %                 imagetune=ImageTuneStruct;
%                                   app.ImageTuneStruct=ImageTuneStruct;
                app.TuneData=ImageTuneStruct.TuneData;
                app.FlowAngle=ImageTuneStruct.FlowAngle
                app.OriginalVideo=ImageTuneStruct.OriginalVideo
                app.Mask=ImageTuneStruct.Mask
                app.CropFrame=ImageTuneStruct.CropFrame
                app.CroppedMask=ImageTuneStruct.CroppedMask
%                 app.BrightnessKnob=ImageTuneStruct.BrightnessKnob
                app.BrightnessKnob.Value=app.TuneData.BrightnessKnob
%                 app.AlphaKnob=app.TuneData.AlphaKnob
                app.AlphaKnob.Value=app.TuneData.AlphaKnob
%                 app.SigmaKnob=app.TuneData.SigmaKnob
                app.SigmaKnob.Value=app.TuneData.SigmaKnob
%                 app.LowInSlider=app.TuneData.LowInSlider
                app.LowInSlider.Value=app.TuneData.LowInSlider
%                 app.HighInSlider=app.TuneData.HighInSlider
                app.HighInSlider.Value=app.TuneData.HighInSlider
%                 app.GammaKnob=app.TuneData.GammaKnob
                app.GammaKnob.Value=app.TuneData.GammaKnob
%                 app.RadiusSlider=app.TuneData.RadiusSlider
                app.RadiusSlider.Value=app.TuneData.RadiusSlider
%                 app.SharpnessstrengthKnob=app.TuneData.SharpnessstrengthKnob
                app.SharpnessstrengthKnob.Value=app.TuneData.SharpnessstrengthKnob
%                 app.ThresholdSlider=app.TuneData.ThresholdSlider
                app.ThresholdSlider.Value=app.TuneData.ThresholdSlider
%                 app.SensitivitySpinner=app.TuneData.SensitivitySpinner
                app.SensitivitySpinner.Value=app.TuneData.SensitivitySpinner
%                 app.TuftcolorButtonGroup=app.TuneData.TuftcolorButtonGroup
                app.TuftcolorButtonGroup.SelectedObject.Text=app.TuneData.TuftcolorButtonGroup
%                 app.MinSpinner=app.TuneData.MinSpinner
                app.MinSpinner.Value=app.TuneData.MinSpinner
%                 app.MaxSpinner=app.TuneData.MaxSpinner
                app.MaxSpinner.Value=app.TuneData.MaxSpinner
                show_part_two(app)
            end
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            app.ImageButton.Visible='off';app.VideoButton.Visible='off';
            app.ChoosedatatypeLabel.Visible='off';
            app.Image.temp=uiimport('-file');
            a=string(fieldnames(app.Image.temp));
            if length(a)==1%jpg
                app.Image=getfield(app.Image.temp,a);
                app.OriginalImage = rgb2gray(app.Image);
            else %png
                index=getfield(app.Image.temp,a(1));
                map=getfield(app.Image.temp,a(2));
                app.Image = ind2rgb(index,map);
                app.OriginalImage = ind2gray(index,map);
            end
            ImageTune(app.Image,app.OriginalImage)
            delete(app.UIFigure)
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            app.ImageButton.Visible='off';app.VideoButton.Visible='off';
            app.ChoosedatatypeLabel.Visible='off';
            app.VideoformatssupportedDropDownLabel.Visible='on';
            app.VideoformatssupportedDropDown.Visible = 'on';
            app.VideofilesDropDownLabel.Visible = 'on';
            app.VideofilesDropDown.Visible = 'on';
            
        end

        % Value changed function: VideoformatssupportedDropDown
        function VideoformatssupportedDropDownValueChanged(app, event)
            VideoformatssupportedDropDownValue = app.VideoformatssupportedDropDown.Value;
            app.VideofilesDropDown.Items = {'none'};
            %detect which videos from the chosen format are in the file
            video_format_choose(app)
        end

        % Value changed function: VideofilesDropDown
        function VideofilesDropDownValueChanged(app, event)
            VideofilesDropDownValue = app.VideofilesDropDown.Value;
            
            if app.VideofilesDropDown.Value~= string('none')
                app.OriginalVideo=VideoReader(app.VideofilesDropDown.Value)
                app.Image = read(app.OriginalVideo, 1);
            end
            app.OriginalImage = rgb2gray(app.Image);
            ImageTune(app.Image,app.OriginalImage,app.OriginalVideo)
            delete(app.UIFigure)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            TuftViz;
        end

        % Button pushed function: UseMachineLearningToolsButton
        function UseMachineLearningToolsButtonPushed(app, event)
            noOfImages=2;
            Main_ML_Training(app,noOfImages)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewMenu = uimenu(app.Menu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.VideoformatssupportedDropDownLabel = uilabel(app.UIFigure);
            app.VideoformatssupportedDropDownLabel.Visible = 'off';
            app.VideoformatssupportedDropDownLabel.Text = 'Video formats supported';
            app.VideoformatssupportedDropDown = uidropdown(app.UIFigure);
            app.VideoformatssupportedDropDown.Items = {'1', 'Option 2', '2', 'Option 4'};
            app.VideoformatssupportedDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoformatssupportedDropDownValueChanged, true);
            app.VideoformatssupportedDropDown.Visible = 'off';
            app.VideoformatssupportedDropDown.Value = '1';
            app.VideofilesDropDownLabel = uilabel(app.UIFigure);
            app.VideofilesDropDownLabel.Visible = 'off';
            app.VideofilesDropDownLabel.Text = 'Video files';
            app.VideofilesDropDown = uidropdown(app.UIFigure);
            app.VideofilesDropDown.ValueChangedFcn = createCallbackFcn(app, @VideofilesDropDownValueChanged, true);
            app.VideofilesDropDown.Visible = 'off';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.VideoButton = uibutton(app.UIFigure, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.ChoosedatatypeLabel = uilabel(app.UIFigure);
            app.ChoosedatatypeLabel.Text = {'Choose the data type'; 'you want to work with'};
            app.UseAnalyticaltoolsButton = uibutton(app.UIFigure, 'push');
            app.UseAnalyticaltoolsButton.Visible = 'off';
            app.UseAnalyticaltoolsButton.Text = {'Use Analytical '; 'tools'};
            app.UseMachineLearningToolsButton = uibutton(app.UIFigure, 'push');
            app.UseMachineLearningToolsButton.ButtonPushedFcn = createCallbackFcn(app, @UseMachineLearningToolsButtonPushed, true);
            app.UseMachineLearningToolsButton.Visible = 'off';
            app.UseMachineLearningToolsButton.Text = {'Use Machine '; 'Learning Tools'};
            app.ChoosedatatypeLabel_2 = uilabel(app.UIFigure);
            app.ChoosedatatypeLabel_2.Visible = 'off';
            app.ChoosedatatypeLabel_2.Text = {'Choose the analysis tools'; 'you want to work with'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TuftVizi(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end